classdef logpose_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        TabGroup                matlab.ui.container.TabGroup
        HOMETab                 matlab.ui.container.Tab
        run_IQ_button           matlab.ui.control.Image
        Image                   matlab.ui.control.Image
        UPLOADIQFILEButton      matlab.ui.control.Button
        ATOOLTOPOSTPROCESSYOUROWNPOSITIONLabel  matlab.ui.control.Label
        StartbyuploadingaIQfileontherightLabel  matlab.ui.control.Label
        WELCOMETOLOGPOSELabel   matlab.ui.control.Label
        IQTab                   matlab.ui.container.Tab
        IQCharacteristicsLabel  matlab.ui.control.Label
        hist_I                  matlab.ui.control.UIAxes
        hist_Q                  matlab.ui.control.UIAxes
        time_I                  matlab.ui.control.UIAxes
        time_Q                  matlab.ui.control.UIAxes
        iq_frq                  matlab.ui.control.UIAxes
        ACQUISITIONTab          matlab.ui.container.Tab
        TRACKINGTab             matlab.ui.container.Tab
        SATELLITEPOSTab         matlab.ui.container.Tab
        USERPOSTab              matlab.ui.container.Tab
    end

    properties (Access = public)
        nav = NavTek; % NavTek class
        iq_file;
        
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Equivalent to main function 
            
            set(app.run_IQ_button,'visible', 'off') % Allows play button to only appear after the uploaded file
            
        end

        % Button pushed function: UPLOADIQFILEButton
        function UPLOADIQFILEButtonPushed(app, event)
            app.iq_file = uigetfile({'.bin'}); % Uploads IQ file date
             if app.iq_file
                 app.nav.get_IQ(app.iq_file);
                 set(app.run_IQ_button,'visible', 'on')
                 app.run_IQ_button.BackgroundColor = [0.00,1.00,0.00]; % For succesfull upload, the button turns green. 
             end   
        end

        % Image clicked function: run_IQ_button
        function run_IQ_buttonClicked(app, event)

            freq_plot = app.nav.probeData(); % Run postProcess function in NavTek.m
            app.TabGroup.SelectedTab = app.IQTab; % Opens the new tab for the IQ Characteristics
        
            % ======= Plotting IQ Characterics ================
            plot(app.iq_frq,freq_plot)

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.HOMETab = uitab(app.TabGroup);
            app.HOMETab.Title = 'HOME';
            app.WELCOMETOLOGPOSELabel = uilabel(app.HOMETab);
            app.WELCOMETOLOGPOSELabel.Text = 'WELCOME TO LOG POSE';
            app.StartbyuploadingaIQfileontherightLabel = uilabel(app.HOMETab);
            app.StartbyuploadingaIQfileontherightLabel.Text = 'Start by uploading a IQ file on the right.';
            app.ATOOLTOPOSTPROCESSYOUROWNPOSITIONLabel = uilabel(app.HOMETab);
            app.ATOOLTOPOSTPROCESSYOUROWNPOSITIONLabel.Text = 'A TOOL TO POST-PROCESS YOUR OWN POSITION.';
            app.UPLOADIQFILEButton = uibutton(app.HOMETab, 'push');
            app.UPLOADIQFILEButton.ButtonPushedFcn = createCallbackFcn(app, @UPLOADIQFILEButtonPushed, true);
            app.UPLOADIQFILEButton.Text = 'UPLOAD IQ FILE ';
            app.Image = uiimage(app.HOMETab);
            app.Image.ImageSource = 'car.png';
            app.run_IQ_button = uiimage(app.HOMETab);
            app.run_IQ_button.ImageClickedFcn = createCallbackFcn(app, @run_IQ_buttonClicked, true);
            app.run_IQ_button.ImageSource = 'play-button.png';
            app.IQTab = uitab(app.TabGroup);
            app.IQTab.Title = 'IQ';
            app.iq_frq = uiaxes(app.IQTab);
            title(app.iq_frq, 'Frequency domain plot')
            xlabel(app.iq_frq, 'X')
            ylabel(app.iq_frq, 'Y')
            zlabel(app.iq_frq, 'Z')
            app.time_Q = uiaxes(app.IQTab);
            title(app.time_Q, 'Time domain plot (Q)')
            xlabel(app.time_Q, 'X')
            ylabel(app.time_Q, 'Y')
            zlabel(app.time_Q, 'Z')
            app.time_I = uiaxes(app.IQTab);
            title(app.time_I, 'Time domain plot (I)')
            xlabel(app.time_I, 'X')
            ylabel(app.time_I, 'Y')
            zlabel(app.time_I, 'Z')
            app.hist_Q = uiaxes(app.IQTab);
            title(app.hist_Q, 'Histogram (Q)')
            xlabel(app.hist_Q, 'X')
            ylabel(app.hist_Q, 'Y')
            zlabel(app.hist_Q, 'Z')
            app.hist_I = uiaxes(app.IQTab);
            title(app.hist_I, 'Histogram (I)')
            xlabel(app.hist_I, 'X')
            ylabel(app.hist_I, 'Y')
            zlabel(app.hist_I, 'Z')
            app.IQCharacteristicsLabel = uilabel(app.IQTab);
            app.IQCharacteristicsLabel.Text = 'IQ Characteristics';
            app.ACQUISITIONTab = uitab(app.TabGroup);
            app.ACQUISITIONTab.Title = 'ACQUISITION';
            app.TRACKINGTab = uitab(app.TabGroup);
            app.TRACKINGTab.Title = 'TRACKING';
            app.SATELLITEPOSTab = uitab(app.TabGroup);
            app.SATELLITEPOSTab.Title = 'SATELLITE POS';
            app.USERPOSTab = uitab(app.TabGroup);
            app.USERPOSTab.Title = 'USER POS';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = logpose_app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end